classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DatasetListBox             matlab.ui.control.ListBox
        DatasetListBoxLabel        matlab.ui.control.Label
        MaskingListBox             matlab.ui.control.ListBox
        MaskingListBoxLabel        matlab.ui.control.Label
        MenuButtonGroup            matlab.ui.container.ButtonGroup
        EdgeDetectedButton         matlab.ui.control.Button
        ProfileExtractionButton_4  matlab.ui.control.Button
        LoadRawImageButton_3       matlab.ui.control.Button
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: EdgeDetectedButton, UIAxes
        function btnEdgeDetected_Callback(app, event)
            folder = uigetdir();
            if folder ~= 0
                app.EdgeFolder = folder;
                files = dir(fullfile(folder, '*.png'));
                app.EdgeFiles = {files.name};
                app.listMasking.Items = app.EdgeFiles;
            end
        end

        % Value changed function: MaskingListBox
        function listMasking_Callback(app, event)
            idx = app.MaskingListBox.Value;
            if isempty (app.EdgeFiles)
                return;
            end
            imgPath = fullfile(app.EdgeFolder, app.EdgeFiles{idx});
            img = imread(imgPath);
            axes(app.UIAxes);
            imshow(img);
        end

        % Button pushed function: LoadRawImageButton_3
        function btnLoadRawImage_Callback(app, event)
            folder = uigetdir();
            if folder ~= 0
                app.RawFolder = folder;
                files = dir(fullfile(folder, '*.png'));
                app.RawFiles = {files.name};
                app.listDataset. Items = app.RawFiles;
        end

        % Value changed function: DatasetListBox
        function listDataset_Callback(app, event)
            idx = app.DatasetListBox.Value;
            if isempty (app.RawFiles)
                return;
            end
            imgPath = fullfile(app.RawFolder, app.RawFiles{idx});
            img = imread (imgPath);
            axes(app.UIAxes_2);
            imshow(img);
        end

        % Button pushed function: ProfileExtractionButton_4
        function btnProfileExtraction_Callback(app, event)
                idx = app.DatasetListBox.Value;
                if isempty(app.RawFiles)
                    return;
                end
                imgPath = fullfile(app.RawFolder, app.RawFiles{idx});
                img = imread(imgPath); 
    
                axes(app.UIAxes2);
                imshow(img);
                hold on;

                % User klik untuk menentukan titik-titik garis
                [x, y] = ginput;  
                plot(x, y, 'r-', 'LineWidth', 1.5);
                
                % Ekstraksi intensitas sepanjang garis
                intensity_values = improfile(img, x, y);
                
                % Plot hasil ekstraksi di AxesIntensity
                axes(app.UIAxes3);
                plot(intensity_values, 'b-', 'LineWidth', 1.5);
                xlabel('Distance');
                ylabel('Intensity');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SCPM Result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @btnEdgeDetected_Callback, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Raw Image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Profile Intensity Extraction')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Extraction Result')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.MenuButtonGroup = uibuttongroup(app.UIFigure);
            app.MenuButtonGroup.Title = 'Menu';
            app.LoadRawImageButton_3 = uibutton(app.MenuButtonGroup, 'push');
            app.LoadRawImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @btnLoadRawImage_Callback, true);
            app.LoadRawImageButton_3.Text = 'Load Raw Image';
            app.ProfileExtractionButton_4 = uibutton(app.MenuButtonGroup, 'push');
            app.ProfileExtractionButton_4.ButtonPushedFcn = createCallbackFcn(app, @btnProfileExtraction_Callback, true);
            app.ProfileExtractionButton_4.Text = 'Profile Extraction';
            app.EdgeDetectedButton = uibutton(app.MenuButtonGroup, 'push');
            app.EdgeDetectedButton.ButtonPushedFcn = createCallbackFcn(app, @btnEdgeDetected_Callback, true);
            app.EdgeDetectedButton.Text = 'Edge Detected';
            app.MaskingListBoxLabel = uilabel(app.UIFigure);
            app.MaskingListBoxLabel.Text = 'Masking';
            app.MaskingListBox = uilistbox(app.UIFigure);
            app.MaskingListBox.ValueChangedFcn = createCallbackFcn(app, @listMasking_Callback, true);
            app.DatasetListBoxLabel = uilabel(app.UIFigure);
            app.DatasetListBoxLabel.Text = 'Dataset';
            app.DatasetListBox = uilistbox(app.UIFigure);
            app.DatasetListBox.ValueChangedFcn = createCallbackFcn(app, @listDataset_Callback, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end